classdef prac8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout5                  matlab.ui.container.GridLayout
        GridLayout6                  matlab.ui.container.GridLayout
        PuntosagenerarporclaseEditField  matlab.ui.control.NumericEditField
        PuntosagenerarporclaseLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout4                  matlab.ui.container.GridLayout
        UmbralEditField              matlab.ui.control.NumericEditField
        PuntosacolocarLabel          matlab.ui.control.Label
        GridLayout3                  matlab.ui.container.GridLayout
        BanderaDropDown              matlab.ui.control.DropDown
        BanderaDropDownLabel         matlab.ui.control.Label
    end

    
    methods (Access = public)
        %Función que sirve para obtener la ruta relativa del archivo
        function ruta = ObtenerRuta(~,value)
                if(value == "Alemania")
                    ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Alemania.jpg";
                elseif(value == "Belgica")
                    ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/belgica.jpeg";
                elseif(value == "Colombia")
                    ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/colombia.png";
                elseif(value == "Holanda")
                    ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Holanda.jpeg";
                elseif(value == "Italia")
                    ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Italia.jpeg";
                elseif(value == "Irlanda")
                    ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Irlanda.jpeg";
                elseif(value == "Luxemburgo")
                    ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/luxemburgo.jpeg";
                elseif(value == "Qatar")
                    ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/qatar.png";
                end            
                return
        end

        %hacer en codigo duro para las dimensiones de las banderas y los
        %colores con impixel y randi([x,x1],1,100)
        %colores con impixel y randi([y,y1],1,100)
        %las banderas son en su mayoria simetricas, por lo que dividir
        %entre n ya sea en x o en y
        %guardar las clases de puntos de las clases
        %Que el usuario indique la cantidad de puntos distribuodos equitativemente y determinar para que valor de T es posible (algoritmo de la teoria de la cadena)
        %x1={g1}
        %x2={g2}
        %g1 si d(x2 ->g1) < umbral
        %dg2 si d(x2 ->g1) >= umbral
        %regla de la cadeena
        %clasificar con un clasificador de los que conocemos

    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BanderaDropDown
        function BanderaDropDownValueChanged(app, event)
            value = app.BanderaDropDown.Value;
            ruta = ObtenerRuta(app,value);
            app.Image.ImageSource = ruta;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.BanderaDropDownLabel = uilabel(app.GridLayout3);
            app.BanderaDropDownLabel.Text = 'Bandera';
            app.BanderaDropDown = uidropdown(app.GridLayout3);
            app.BanderaDropDown.Items = {'Alemania', 'Belgica', 'Colombia', 'Holanda', 'Irlanda', 'Italia', 'Luxemburgo', 'Qatar'};
            app.BanderaDropDown.ValueChangedFcn = createCallbackFcn(app, @BanderaDropDownValueChanged, true);
            app.BanderaDropDown.Placeholder = 'seleccionar';
            app.BanderaDropDown.Value = 'Alemania';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.PuntosacolocarLabel = uilabel(app.GridLayout4);
            app.PuntosacolocarLabel.Text = 'Umbral';
            app.UmbralEditField = uieditfield(app.GridLayout4, 'numeric');
            app.UmbralEditField.Limits = [1 100];
            app.UmbralEditField.RoundFractionalValues = 'on';
            app.UmbralEditField.ValueDisplayFormat = '%.0f';
            app.UmbralEditField.Value = 1;
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Alemania.jpg');
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.PuntosagenerarporclaseLabel = uilabel(app.GridLayout6);
            app.PuntosagenerarporclaseLabel.Text = {'Puntos a '; 'generar por clase'};
            app.PuntosagenerarporclaseEditField = uieditfield(app.GridLayout6, 'numeric');
            app.PuntosagenerarporclaseEditField.Limits = [4 1000];
            app.PuntosagenerarporclaseEditField.ValueDisplayFormat = '%.0f';
            app.PuntosagenerarporclaseEditField.Value = 4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prac8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end